classdef Edge_Detection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartEdgeDetectionButton  matlab.ui.control.Button
        Image                     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartEdgeDetectionButton
        function StartEdgeDetectionButtonPushed(app, event)
            I = app.Image.ImageSource;
            I = rgb2gray(I);
            b = edge(I,'roberts');
            c = edge(I,'sobel');
            d = edge(I,'prewitt');
            e = edge(I,'canny');
            f = edge(I,'log');
            subplot(2,3,1), imshow(I),title('Original Image (Greyscaled)');
            subplot(2,3,2), imshow(b),title('Roberts Image');
            subplot(2,3,3), imshow(c),title('Sobel Image');
            subplot(2,3,4), imshow(d),title('Prewitt Image');
            subplot(2,3,5), imshow(e),title('Canny Image');
            subplot(2,3,6), imshow(f),title('Log Image');
            set(gcf,'WindowState','maximized');
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [filename,pathname] = uigetfile({'*.png; *.jpg; *.jpeg'});
            app.Image.ImageSource = imread(fullfile(pathname,filename));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.StartEdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.StartEdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartEdgeDetectionButtonPushed, true);
            app.StartEdgeDetectionButton.Text = 'Start Edge Detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Edge_Detection_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end